classdef PressureControllerSample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        WaveGeneratorButton  matlab.ui.control.Button
        RightPanel           matlab.ui.container.Panel
        UIAxes               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        WaveformApp                   % Dialog box app
        WaveType = 'Sine Wave';       % Wave type
        TargetPressure = 500;         % in hPa
        Amplitude = 100;              % in hPa
        Frequency = 1;                % in Hz
        SampleRate = 5;               % in Hz
    end
    
    methods (Access = public)
        
        function updateplot(app,WaveType,TargetPressure,Amplitude,Frequency,SampleRate) 
            % Store inputs as properties
            app.WaveType = WaveType;
            app.TargetPressure = TargetPressure;
            app.Amplitude = Amplitude;
            app.Frequency = Frequency;
            app.SampleRate = SampleRate;
            x = 0:1/app.SampleRate:15;
            
            % Drawing Waves
            if strcmp(app.WaveType, 'Sine Wave')
                plot(app.UIAxes,x,sin(x*app.Frequency)*app.Amplitude+app.TargetPressure)
            elseif strcmp(app.WaveType, 'Square Wave')
                plot(app.UIAxes,x,square(x*app.Frequency)*app.Amplitude+app.TargetPressure)
            elseif strcmp(app.WaveType, 'Triangular Wave')
                plot(app.UIAxes,x,sawtooth(x*app.Frequency,0.5)*app.Amplitude+app.TargetPressure)
            elseif strcmp(app.WaveType, 'Ramp Wave Positive')
                plot(app.UIAxes,x,sawtooth(x*app.Frequency)*app.Amplitude+app.TargetPressure)
            elseif strcmp(app.WaveType, 'Ramp Wave Negative')
                plot(app.UIAxes,x,sawtooth(x*app.Frequency,0)*app.Amplitude+app.TargetPressure)
            else
                plot(app.UIAxes,[0 15],[1000 1000])
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,[7.5 5.5],[0 1000])
                plot(app.UIAxes,[0 15],[0 0])
                hold(app.UIAxes, 'off');
            end
            
            % Re-enable the WaveGenerator button
            app.WaveGeneratorButton.Enable = 'on';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: WaveGeneratorButton
        function WaveGeneratorButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.WaveGeneratorButton.Enable = 'off';
    
            % Call dialog box with input values
            app.WaveformApp = Waveforms(app,app.WaveType,app.TargetPressure,app.Amplitude,app.Frequency,app.SampleRate);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Delete both apps
            delete(app.WaveformApp)
            delete(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {109, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.WaveGeneratorButton = uibutton(app.LeftPanel, 'push');
            app.WaveGeneratorButton.ButtonPushedFcn = createCallbackFcn(app, @WaveGeneratorButtonPushed, true);
            app.WaveGeneratorButton.Text = 'Wave Generator';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Pressure Controller')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Pressure (hPa)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PressureControllerSample

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end