classdef SignalGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        DownButton                   matlab.ui.control.Button
        UpButton                     matlab.ui.control.Button
        CurrentPressurehPaEditField  matlab.ui.control.NumericEditField
        CurrentPressurehPaEditFieldLabel  matlab.ui.control.Label
        TypeEditField                matlab.ui.control.EditField
        TypeEditFieldLabel           matlab.ui.control.Label
        SignalSettingsButton         matlab.ui.control.Button
        RightPanel                   matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
        WaveStruct = struct('WaveType','Constant',... % Wave type
                            'OffsetPressure',500,...  % in hPa
                            'Amplitude',100,...       % in hPa
                            'Period',5,...            % in s
                            'Frequency',0.2,...       % in Hz
                            'SampleRate',4,...        % in Hz
                            'Repeat',0,...
                            'HalfPulse',0);
                                    
        GlobalHandles;                                % Struct to store handles
        
        % Pressure Vectors
        TimeVect = (-15:1/4:0)';                      % Vector for real-time plots
        TimeAxis = 0:1/4:15;                          % Time for plot preview
        PressureTar = zeros(61,1);                    % Plotted set pressure
        PressureValue =  zeros(61,1);                 % Snipped pressure waveform values
        PressurePreview = zeros(61,1);                % Pressure plotted on preview panel
        PressureTemp = zeros(61,1);                   % Temporarily stored pressure values
    end
    
    methods (Access = private)
        % Timer Function
        function TimerFunction(app)
            %disp('Plotting Now!')
            if app.PressureTemp(1) == -1
                if strcmp(app.WaveStruct.WaveType, 'Ramp') && app.WaveStruct.HalfPulse
                    app.PressureTar(end) = app.WaveStruct.OffsetPressure+app.WaveStruct.Amplitude;
                else
                    app.PressureTar(end) = app.WaveStruct.OffsetPressure;
                end
                app.PressureTemp(2) = -1;
            else
                app.PressureTar(end) = app.PressureTemp(1);
            end
            
            % Display Current Pressure
            app.CurrentPressurehPaEditField.Value = round(app.PressureTar(end));
            
            % Update Graph
            set(app.GlobalHandles.hP,'XData', app.TimeVect)
            set(app.GlobalHandles.hP,'YData', app.PressureTar)
            app.PressureTemp = circshift(app.PressureTemp, -1);
            app.PressureTar = circshift(app.PressureTar, -1);
        end
        
        % Function for the Popup Type ValueChangedFcn callback
        function PopupTypeDropDownChanged(app)
            app.WaveStruct.WaveType = app.GlobalHandles.PopupTypeDropDown.Value;
            PlotPreview(app)
        end
        
        % Function for the Offset Pressure ValueChangedFcn callback
        function OffsetPressureValueChanged(app)
            PlotPreview(app)
        end
        
        % Function for the Amplitude ValueChangedFcn callback
        function AmplitudeValueChanged(app)
            PlotPreview(app)
        end
        
        % Function for the Period ValueChangedFcn callback
        function PeriodValueChanged(app)
            app.GlobalHandles.FrequencyEditField.Value = 1/app.GlobalHandles.PeriodEditField.Value;
            PlotPreview(app)
        end
        
        % Function for the Frequency ValueChangedFcn callback
        function FrequencyValueChanged(app)
            app.GlobalHandles.PeriodEditField.Value = 1/app.GlobalHandles.FrequencyEditField.Value;
            PlotPreview(app)
        end
        
        % Function for the Repeat ValueChangedFcn callback
        function RepeatValueChanged(app)
            PlotPreview(app)
        end
        
        % Function for the HalfPulse ValueChangedFcn callback
        function HalfPulseValueChanged(app)
            PlotPreview(app)
        end
        
        % Function for the Cancel ButtonPushedFcn callback
        function CancelButtonPushed(app)
            delete(app.GlobalHandles.SignalFigure)
            % Re-enable the Signal Settings button
            app.SignalSettingsButton.Enable = 'on';
        end
        
        % Function for the Confirm ButtonPushedFcn callback
        function ConfirmButtonPushed(app)
            % Error Checks
            if app.GlobalHandles.ErrorFlag == 1
                uialert(app.UIFigure,'Max pressure is over 1000 hPa! Pressures above 1000 hPa will be capped at 1000 hPa','Pressure input warning');
            elseif app.GlobalHandles.ErrorFlag == -1
                uialert(app.UIFigure,'Min pressure is below 0 hPa! Pressures below 0 hPa will be floored at 0 hPa','Pressure input warning');
            end
            
            % Edit Type Field
            if app.GlobalHandles.RepeatCheckbox.Value
                if app.GlobalHandles.HalfPulseCheckbox.Value
                    app.TypeEditField.Value = append(app.GlobalHandles.PopupTypeDropDown.Value,' Half Wave');
                else
                    app.TypeEditField.Value = append(app.GlobalHandles.PopupTypeDropDown.Value,' Wave');
                end
            else
                if app.GlobalHandles.HalfPulseCheckbox.Value
                    app.TypeEditField.Value = append(app.GlobalHandles.PopupTypeDropDown.Value,' Half Pulse');
                else
                    app.TypeEditField.Value = append(app.GlobalHandles.PopupTypeDropDown.Value,' Pulse');
                end
            end
            
            % Update all other values into WaveStruct and PressureTemp
            app.PressureTemp = app.PressureValue;
            app.WaveStruct.OffsetPressure = app.GlobalHandles.OffsetPressureEditField.Value;
            app.WaveStruct.Amplitude = app.GlobalHandles.AmplitudeEditField.Value;
            app.WaveStruct.Period = app.GlobalHandles.PeriodEditField.Value;
            app.WaveStruct.Frequency = app.GlobalHandles.FrequencyEditField.Value;
            app.WaveStruct.Repeat = app.GlobalHandles.RepeatCheckbox.Value;
            app.WaveStruct.HalfPulse = app.GlobalHandles.HalfPulseCheckbox.Value;
            delete(app.GlobalHandles.SignalFigure)
            
            % Re-enable the Signal Settings button
            app.SignalSettingsButton.Enable = 'on';
        end
        
        % Function for the Confirm ButtonPushedFcn callback
        function SelectFileButtonPushed(app)
            % Read File
            CustomSignal = uigetfile({'*.xlsx';'*.xls';'*.csv*';'*.txt';'*.*'},'File Selector');
            
            if CustomSignal==0
                errordlg('Please select an Excel Spreadsheet!','File not Selected!');
            else
                % Get options
                opts = detectImportOptions(CustomSignal);
                opts.VariableNames = {'Time','Pressure'};
                
                % Read Table
                CustomTable = readtable(CustomSignal,opts);
                Time = CustomTable.Time;
                Pressure = CustomTable.Pressure;
                
                % Modify variables
                app.GlobalHandles.PeriodEditField.Value = max(Time);
                app.GlobalHandles.OffsetPressureEditField.Value = Pressure(end);
                RepeatVector = zeros(length(Time),1);
                for i=1:length(Time)-1
                    RepeatVector(i) = length(Time(i):1/app.WaveStruct.SampleRate:Time(i+1))-1;
                end
                RepeatVector(end) = 1;
                app.PressurePreview = repelem(Pressure,RepeatVector);
                
                % Make figures appear again
                figure(app.UIFigure);
                figure(app.GlobalHandles.SignalFigure);
                
                app.TimeAxis = 0:1/app.WaveStruct.SampleRate:app.GlobalHandles.PeriodEditField.Value;
                PlotPreview(app)
            end
        end
        
        % Function for Plotting Waves
        function PlotPreview(app) 
            % Change app.TimeAxis
            if ~strcmp(app.WaveStruct.WaveType, 'Custom')
                app.TimeAxis = 0:1/app.WaveStruct.SampleRate:app.GlobalHandles.PeriodEditField.Value*2;
            end
            
            % Drawing Waves
            % Constant Pressure
            if strcmp(app.WaveStruct.WaveType, 'Constant')
                % Making buttons appear/disappear 
                app.GlobalHandles.SelectFileButton.Visible = 'off';
                app.GlobalHandles.OffsetPressureTextField.Visible = 'on';
                app.GlobalHandles.OffsetPressureEditField.Visible = 'on';
                app.GlobalHandles.AmplitudeTextField.Visible = 'off';
                app.GlobalHandles.AmplitudeEditField.Visible = 'off';
                app.GlobalHandles.PeriodTextField.Visible = 'off';
                app.GlobalHandles.PeriodEditField.Visible = 'off';
                app.GlobalHandles.FrequencyTextField.Visible = 'off';
                app.GlobalHandles.FrequencyEditField.Visible = 'off';
                app.GlobalHandles.RepeatCheckbox.Visible = 'off';
                app.GlobalHandles.HalfPulseCheckbox.Visible = 'off';
                
                % Create Signal
                app.PressurePreview = ones(length(app.TimeAxis),1)*app.GlobalHandles.OffsetPressureEditField.Value;
            % Sine
            elseif strcmp(app.WaveStruct.WaveType, 'Sine')
                % Making buttons appear/disappear
                app.GlobalHandles.SelectFileButton.Visible = 'off';
                app.GlobalHandles.OffsetPressureTextField.Visible = 'on';
                app.GlobalHandles.OffsetPressureEditField.Visible = 'on';
                app.GlobalHandles.AmplitudeTextField.Visible = 'on';
                app.GlobalHandles.AmplitudeEditField.Visible = 'on';
                app.GlobalHandles.PeriodTextField.Visible = 'on';
                app.GlobalHandles.PeriodEditField.Visible = 'on';
                app.GlobalHandles.FrequencyTextField.Visible = 'on';
                app.GlobalHandles.FrequencyEditField.Visible = 'on';
                app.GlobalHandles.RepeatCheckbox.Visible = 'on';
                app.GlobalHandles.HalfPulseCheckbox.Visible = 'on';
         
                % Create Signal       
                if app.GlobalHandles.RepeatCheckbox.Value
                    if app.GlobalHandles.HalfPulseCheckbox.Value
                        app.PressurePreview = abs(sin(app.TimeAxis*app.GlobalHandles.FrequencyEditField.Value*2*pi))*app.GlobalHandles.AmplitudeEditField.Value+app.GlobalHandles.OffsetPressureEditField.Value;
                    else
                        app.PressurePreview = sin(app.TimeAxis*app.GlobalHandles.FrequencyEditField.Value*2*pi)*app.GlobalHandles.AmplitudeEditField.Value+app.GlobalHandles.OffsetPressureEditField.Value;
                    end
                else
                    if app.GlobalHandles.HalfPulseCheckbox.Value
                        app.PressurePreview = sin(app.TimeAxis*app.GlobalHandles.FrequencyEditField.Value*2*pi)*app.GlobalHandles.AmplitudeEditField.Value+app.GlobalHandles.OffsetPressureEditField.Value;
                        app.PressurePreview(app.TimeAxis>=app.GlobalHandles.PeriodEditField.Value/2) = app.GlobalHandles.OffsetPressureEditField.Value;
                    else
                        app.PressurePreview = sin(app.TimeAxis*app.GlobalHandles.FrequencyEditField.Value*2*pi)*app.GlobalHandles.AmplitudeEditField.Value+app.GlobalHandles.OffsetPressureEditField.Value;
                        app.PressurePreview(app.TimeAxis>=app.GlobalHandles.PeriodEditField.Value) = app.GlobalHandles.OffsetPressureEditField.Value;
                    end
                end
            % Square
            elseif strcmp(app.WaveStruct.WaveType, 'Square')
                % Making buttons appear/disappear
                app.GlobalHandles.SelectFileButton.Visible = 'off';
                app.GlobalHandles.OffsetPressureTextField.Visible = 'on';
                app.GlobalHandles.OffsetPressureEditField.Visible = 'on';
                app.GlobalHandles.AmplitudeTextField.Visible = 'on';
                app.GlobalHandles.AmplitudeEditField.Visible = 'on';
                app.GlobalHandles.PeriodTextField.Visible = 'on';
                app.GlobalHandles.PeriodEditField.Visible = 'on';
                app.GlobalHandles.FrequencyTextField.Visible = 'on';
                app.GlobalHandles.FrequencyEditField.Visible = 'on';
                app.GlobalHandles.RepeatCheckbox.Visible = 'on';
                app.GlobalHandles.HalfPulseCheckbox.Visible = 'on';
                
                % Create Signal
                if app.GlobalHandles.RepeatCheckbox.Value
                    if app.GlobalHandles.HalfPulseCheckbox.Value
                        app.PressurePreview = abs(square(app.TimeAxis*app.GlobalHandles.FrequencyEditField.Value*2*pi))*app.GlobalHandles.AmplitudeEditField.Value+app.GlobalHandles.OffsetPressureEditField.Value;
                    else
                        app.PressurePreview = square(app.TimeAxis*app.GlobalHandles.FrequencyEditField.Value*2*pi)*app.GlobalHandles.AmplitudeEditField.Value+app.GlobalHandles.OffsetPressureEditField.Value;
                    end
                else
                    if app.GlobalHandles.HalfPulseCheckbox.Value
                        app.PressurePreview = square(app.TimeAxis*app.GlobalHandles.FrequencyEditField.Value*2*pi)*app.GlobalHandles.AmplitudeEditField.Value+app.GlobalHandles.OffsetPressureEditField.Value;
                        app.PressurePreview(app.TimeAxis>=app.GlobalHandles.PeriodEditField.Value/2) = app.GlobalHandles.OffsetPressureEditField.Value;
                    else
                        app.PressurePreview = square(app.TimeAxis*app.GlobalHandles.FrequencyEditField.Value*2*pi)*app.GlobalHandles.AmplitudeEditField.Value+app.GlobalHandles.OffsetPressureEditField.Value;
                        app.PressurePreview(app.TimeAxis>=app.GlobalHandles.PeriodEditField.Value) = app.GlobalHandles.OffsetPressureEditField.Value;
                    end
                end
            % Triangular (Sawtooth)
            elseif strcmp(app.WaveStruct.WaveType, 'Triangular')
                % Making buttons appear/disappear
                app.GlobalHandles.SelectFileButton.Visible = 'off';
                app.GlobalHandles.OffsetPressureTextField.Visible = 'on';
                app.GlobalHandles.OffsetPressureEditField.Visible = 'on';
                app.GlobalHandles.AmplitudeTextField.Visible = 'on';
                app.GlobalHandles.AmplitudeEditField.Visible = 'on';
                app.GlobalHandles.PeriodTextField.Visible = 'on';
                app.GlobalHandles.PeriodEditField.Visible = 'on';
                app.GlobalHandles.FrequencyTextField.Visible = 'on';
                app.GlobalHandles.FrequencyEditField.Visible = 'on';
                app.GlobalHandles.RepeatCheckbox.Visible = 'on';
                app.GlobalHandles.HalfPulseCheckbox.Visible = 'on';
                
                % Create Signal
                if app.GlobalHandles.RepeatCheckbox.Value
                    if app.GlobalHandles.HalfPulseCheckbox.Value
                        app.PressurePreview = abs(sawtooth((app.TimeAxis)*app.GlobalHandles.FrequencyEditField.Value*2*pi+pi/2,0.5))*app.GlobalHandles.AmplitudeEditField.Value+app.GlobalHandles.OffsetPressureEditField.Value;
                    else
                        app.PressurePreview = sawtooth(app.TimeAxis*app.GlobalHandles.FrequencyEditField.Value*2*pi+pi/2,0.5)*app.GlobalHandles.AmplitudeEditField.Value+app.GlobalHandles.OffsetPressureEditField.Value;
                    end
                else
                    if app.GlobalHandles.HalfPulseCheckbox.Value
                        app.PressurePreview = sawtooth(app.TimeAxis*app.GlobalHandles.FrequencyEditField.Value*2*pi+pi/2,0.5)*app.GlobalHandles.AmplitudeEditField.Value+app.GlobalHandles.OffsetPressureEditField.Value;
                        app.PressurePreview(app.TimeAxis>=app.GlobalHandles.PeriodEditField.Value/2) = app.GlobalHandles.OffsetPressureEditField.Value;
                    else
                        app.PressurePreview = sawtooth(app.TimeAxis*app.GlobalHandles.FrequencyEditField.Value*2*pi+pi/2,0.5)*app.GlobalHandles.AmplitudeEditField.Value+app.GlobalHandles.OffsetPressureEditField.Value;
                        app.PressurePreview(app.TimeAxis>=app.GlobalHandles.PeriodEditField.Value) = app.GlobalHandles.OffsetPressureEditField.Value;
                    end
                end            
            % Ramp
            elseif strcmp(app.WaveStruct.WaveType, 'Ramp')
                % Making buttons appear/disappear
                app.GlobalHandles.SelectFileButton.Visible = 'off';
                app.GlobalHandles.OffsetPressureTextField.Visible = 'on';
                app.GlobalHandles.OffsetPressureEditField.Visible = 'on';
                app.GlobalHandles.AmplitudeTextField.Visible = 'on';
                app.GlobalHandles.AmplitudeEditField.Visible = 'on';
                app.GlobalHandles.PeriodTextField.Visible = 'on';
                app.GlobalHandles.PeriodEditField.Visible = 'on';
                app.GlobalHandles.FrequencyTextField.Visible = 'on';
                app.GlobalHandles.FrequencyEditField.Visible = 'on';
                app.GlobalHandles.RepeatCheckbox.Visible = 'on';
                app.GlobalHandles.HalfPulseCheckbox.Visible = 'on';
                
                % Create Signal
                if app.GlobalHandles.RepeatCheckbox.Value
                    if app.GlobalHandles.HalfPulseCheckbox.Value
                        app.PressurePreview = abs(sawtooth(app.TimeAxis*app.GlobalHandles.FrequencyEditField.Value*2*pi+pi))*app.GlobalHandles.AmplitudeEditField.Value+app.GlobalHandles.OffsetPressureEditField.Value;
                    else
                        app.PressurePreview = sawtooth(app.TimeAxis*app.GlobalHandles.FrequencyEditField.Value*2*pi+pi)*app.GlobalHandles.AmplitudeEditField.Value+app.GlobalHandles.OffsetPressureEditField.Value;
                    end
                else
                    if app.GlobalHandles.HalfPulseCheckbox.Value
                        app.PressurePreview = sawtooth(app.TimeAxis*app.GlobalHandles.FrequencyEditField.Value*2*pi+pi)*app.GlobalHandles.AmplitudeEditField.Value+app.GlobalHandles.OffsetPressureEditField.Value;
                        app.PressurePreview(app.TimeAxis>=app.GlobalHandles.PeriodEditField.Value/2) = app.GlobalHandles.AmplitudeEditField.Value+app.GlobalHandles.OffsetPressureEditField.Value;
                    else
                        app.PressurePreview = sawtooth(app.TimeAxis*app.GlobalHandles.FrequencyEditField.Value*2*pi+pi)*app.GlobalHandles.AmplitudeEditField.Value+app.GlobalHandles.OffsetPressureEditField.Value;
                        app.PressurePreview(app.TimeAxis>=app.GlobalHandles.PeriodEditField.Value) = app.GlobalHandles.OffsetPressureEditField.Value;
                    end
                end
            % Custom Built Wave
            elseif strcmp(app.WaveStruct.WaveType, 'Custom')
                % Making buttons appear/disappear
                app.GlobalHandles.SelectFileButton.Visible = 'on';
                app.GlobalHandles.OffsetPressureTextField.Visible = 'off';
                app.GlobalHandles.OffsetPressureEditField.Visible = 'off';
                app.GlobalHandles.AmplitudeTextField.Visible = 'off';
                app.GlobalHandles.AmplitudeEditField.Visible = 'off';
                app.GlobalHandles.PeriodTextField.Visible = 'on';
                app.GlobalHandles.PeriodEditField.Visible = 'on';
                app.GlobalHandles.FrequencyTextField.Visible = 'off';
                app.GlobalHandles.FrequencyEditField.Visible = 'off';
                app.GlobalHandles.RepeatCheckbox.Visible = 'on';
                app.GlobalHandles.HalfPulseCheckbox.Visible = 'off';
            end
            
            % Error Checks
            PressureCheck = app.PressurePreview;
            PressureCheck(PressureCheck == -1) = 500;
            if max(PressureCheck)>1000
                uialert(app.GlobalHandles.SignalFigure,'Max pressure is over 1000 hPa! Pressures above 1000 hPa will be capped at 1000 hPa','Pressure input warning');
                app.GlobalHandles.ErrorFlag = 1;
            elseif min(PressureCheck)<0
                uialert(app.GlobalHandles.SignalFigure,'Min pressure is below 0 hPa! Pressures below 0 hPa will be floored at 0 hPa','Pressure input warning');
                app.GlobalHandles.ErrorFlag = -1;
            else
                app.GlobalHandles.ErrorFlag = 0;
            end
            app.PressurePreview(app.PressurePreview>1000) = 1000;
            app.PressurePreview(app.PressurePreview==-1) = 2000;
            app.PressurePreview(app.PressurePreview<0) = 0;
            app.PressurePreview(app.PressurePreview==2000) = -1;
            
            % Plot with Calculated Pressure Values
            if strcmp(app.WaveStruct.WaveType, 'Constant')
                plot(app.GlobalHandles.PreviewAxes,app.TimeAxis,app.PressurePreview, 'b')
            else
                stem(app.GlobalHandles.PreviewAxes,app.TimeAxis,app.PressurePreview, 'b')
            end
            
            % Snip wave with and without repeat
            app.PressureValue = app.PressurePreview;
            app.PressureValue = app.PressureValue(app.TimeAxis<app.GlobalHandles.PeriodEditField.Value);
            if ~(app.GlobalHandles.RepeatCheckbox.Value)
                app.PressureValue(end+1) = -1;
            else
                app.PressureValue(app.PressureValue==-1) = [];
            end
        end
        
        % If closing the pop up window
        function SignalFigureCloseRequest(app)
            delete(app.GlobalHandles.SignalFigure)
            
            % Re-enable the Signal Settings button
            app.SignalSettingsButton.Enable = 'on';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.GlobalHandles.RepeatCheckbox.Value = app.WaveStruct.Repeat;
            app.GlobalHandles.HalfPulseCheckbox.Value = app.WaveStruct.HalfPulse;
            app.GlobalHandles.hP = plot(app.UIAxes,app.TimeVect,app.PressureTar);
            Timer = timer;
            Timer.Period = 0.25;
            Timer.ExecutionMode = 'fixedRate';
            Timer.TimerFcn = {@(Timer,event) TimerFunction(app)};
            app.GlobalHandles.Timer = Timer;
            start(app.GlobalHandles.Timer)
            
            % Up and down spinners
            app.UpButton.Text = append(char(9650),'+5');
            app.DownButton.Text = append(char(9660),'-5');
        end

        % Button pushed function: SignalSettingsButton
        function SignalSettingsButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.SignalSettingsButton.Enable = 'off';
            
            % Create new UIFigure with input values
            SignalFigure = uifigure('Name','Signal Generator');
            SignalFigure.CloseRequestFcn = @(SignalFigure,event) SignalFigureCloseRequest(app);
            app.GlobalHandles.SignalFigure = SignalFigure;
            
            % Axes Settings
            PreviewAxes = uiaxes(SignalFigure);
            PreviewAxes.Position = [5, 5, 350, 400];
            PreviewAxes.Title.String = 'Wave Preview';
            PreviewAxes.XLabel.String = 'Time (s)';
            PreviewAxes.YLabel.String = 'Pressure (hPa)';
            app.GlobalHandles.PreviewAxes = PreviewAxes;
            
            % Type Text Field
            TypeTextField = uilabel(SignalFigure);
            TypeTextField.Text = 'Wave Type';
            TypeTextField.Position = [360, 308, 60, 22];
            
            % Type Dropdown
            PopupTypeDropDown = uidropdown(SignalFigure);
            PopupTypeDropDown.Position = [430, 308, 110, 22];
            PopupTypeDropDown.Items = {'Constant','Sine','Square','Triangular','Ramp','Custom'};
            PopupTypeDropDown.Value = app.WaveStruct.WaveType;
            PopupTypeDropDown.ValueChangedFcn = {@(PopupTypeDropDown,event) PopupTypeDropDownChanged(app)};
            app.GlobalHandles.PopupTypeDropDown = PopupTypeDropDown;
            
            % Offset Pressure Text Field
            OffsetPressureTextField = uilabel(SignalFigure);
            OffsetPressureTextField.Text = 'Offset Pressure (hPa)';
            OffsetPressureTextField.Position = [360, 278, 120, 22];
            app.GlobalHandles.OffsetPressureTextField = OffsetPressureTextField;
            
            % Offset Pressure Edit Field
            OffsetPressureEditField = uieditfield(SignalFigure,'numeric');
            OffsetPressureEditField.Value = app.WaveStruct.OffsetPressure;
            OffsetPressureEditField.Limits = [0 1000];
            OffsetPressureEditField.Position = [480, 278, 60, 22];
            OffsetPressureEditField.ValueChangedFcn = @(OffsetPressureEditField,event) OffsetPressureValueChanged(app);
            app.GlobalHandles.OffsetPressureEditField = OffsetPressureEditField;
            
            % Amplitude Text Field
            AmplitudeTextField = uilabel(SignalFigure);
            AmplitudeTextField.Text = 'Amplitude (hPa)';
            AmplitudeTextField.Position = [360, 248, 120, 22];
            app.GlobalHandles.AmplitudeTextField = AmplitudeTextField;
            
            % Amplitude Edit Field
            AmplitudeEditField = uieditfield(SignalFigure,'numeric');
            AmplitudeEditField.Value = app.WaveStruct.Amplitude;
            AmplitudeEditField.Limits = [-1000 1000];
            AmplitudeEditField.Position = [480, 248, 60, 22];
            AmplitudeEditField.ValueChangedFcn = @(AmplitudeEditField,event) AmplitudeValueChanged(app);
            app.GlobalHandles.AmplitudeEditField = AmplitudeEditField;
            
            % Period Text Field
            PeriodTextField = uilabel(SignalFigure);
            PeriodTextField.Text = 'Period (s)';
            PeriodTextField.Position = [360, 208, 120, 22];
            app.GlobalHandles.PeriodTextField = PeriodTextField;
            
            % Period Edit Field
            PeriodEditField = uieditfield(SignalFigure,'numeric');
            PeriodEditField.Value = app.WaveStruct.Period;
            PeriodEditField.Position = [480, 208, 60, 22];
            PeriodEditField.ValueChangedFcn = @(PeriodEditField,event) PeriodValueChanged(app);
            app.GlobalHandles.PeriodEditField = PeriodEditField;
            
            % Frequency Text Field
            FrequencyTextField = uilabel(SignalFigure);
            FrequencyTextField.Text = 'Frequency (Hz)';
            FrequencyTextField.Position = [360, 178, 120, 22];
            app.GlobalHandles.FrequencyTextField = FrequencyTextField;
            
            % Frequency Edit Field
            FrequencyEditField = uieditfield(SignalFigure,'numeric');
            FrequencyEditField.Value = app.WaveStruct.Frequency;
            FrequencyEditField.Position = [480, 178, 60, 22];
            FrequencyEditField.ValueChangedFcn = @(FrequencyEditField,event) FrequencyValueChanged(app);
            app.GlobalHandles.FrequencyEditField = FrequencyEditField;
            
            % Repeat Checkbox
            RepeatCheckbox = uicheckbox(SignalFigure);
            RepeatCheckbox.Text = 'Repeat';
            RepeatCheckbox.Position = [410 138 100 22];
            RepeatCheckbox.Value = app.WaveStruct.Repeat;
            RepeatCheckbox.ValueChangedFcn = @(RepeatCheckbox,event) RepeatValueChanged(app);
            app.GlobalHandles.RepeatCheckbox = RepeatCheckbox;
            
            % Half Pulse Checkbox
            HalfPulseCheckbox = uicheckbox(SignalFigure);
            HalfPulseCheckbox.Text = 'Half Pulse';
            HalfPulseCheckbox.Position = [410 108 100 22];
            HalfPulseCheckbox.Value = app.WaveStruct.HalfPulse;
            HalfPulseCheckbox.ValueChangedFcn = @(HalfPulseCheckbox,event) HalfPulseValueChanged(app);
            app.GlobalHandles.HalfPulseCheckbox = HalfPulseCheckbox;
            
            % Cancel Button to Preview Signal
            CancelButton = uibutton(SignalFigure);
            CancelButton.Text = 'Cancel';
            CancelButton.Position = [410, 68, 100, 22];
            CancelButton.ButtonPushedFcn = {@(CancelButton,event) CancelButtonPushed(app)};
            app.GlobalHandles.CancelButton = CancelButton;
            
            % Confirm Button to Set Signal
            ConfirmButton = uibutton(SignalFigure);
            ConfirmButton.BackgroundColor = 'g';
            ConfirmButton.Text = 'Confirm';
            ConfirmButton.Position = [410, 28, 100, 22];
            ConfirmButton.ButtonPushedFcn = {@(ConfirmButton,event) ConfirmButtonPushed(app)};
            app.GlobalHandles.ConfirmButton = ConfirmButton;
            
            % Select File Button to Set Signal
            SelectFileButton = uibutton(SignalFigure);
            SelectFileButton.BackgroundColor = 'g';
            SelectFileButton.Text = 'Select File';
            SelectFileButton.Position = [410, 278, 100, 22];
            SelectFileButton.ButtonPushedFcn = {@(SelectFileButton,event) SelectFileButtonPushed(app)};
            app.GlobalHandles.SelectFileButton = SelectFileButton;
            
            % Initial Plot
            PlotPreview(app)

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Delete app
            stop(app.GlobalHandles.Timer)
            delete(app.GlobalHandles.Timer)
            delete(app)
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            % Error Checks
            PressureCheck = app.PressureTemp;
            PressureCheck(PressureCheck == -1) = 500;
            if max(PressureCheck+5)>1000 || app.WaveStruct.OffsetPressure+5>1000
                % Pressure too high
                uialert(app.UIFigure,'Max pressure is over 1000 hPa! Pressure Change Reverted','Pressure input error');
            else
                app.PressureTemp(app.PressureTemp~=-1) = app.PressureTemp(app.PressureTemp~=-1)+5;
                app.WaveStruct.OffsetPressure = app.WaveStruct.OffsetPressure+5;
            end
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            % Error Checks
            PressureCheck = app.PressureTemp;
            PressureCheck(PressureCheck == -1) = 500;
            if min(PressureCheck-5)<0 || app.WaveStruct.OffsetPressure-5<0
                % Pressure too low
                uialert(app.UIFigure,'Min pressure is below 0 hPa! Pressure Change Reverted','Pressure input error');
            else
                app.PressureTemp(app.PressureTemp~=-1) = app.PressureTemp(app.PressureTemp~=-1)-5;
                app.WaveStruct.OffsetPressure = app.WaveStruct.OffsetPressure-5;
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {189, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SignalSettingsButton = uibutton(app.LeftPanel, 'push');
            app.SignalSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SignalSettingsButtonPushed, true);
            app.SignalSettingsButton.Text = 'Signal Settings';
            app.TypeEditFieldLabel = uilabel(app.LeftPanel);
            app.TypeEditFieldLabel.Text = 'Type';
            app.TypeEditField = uieditfield(app.LeftPanel, 'text');
            app.TypeEditField.Editable = 'off';
            app.TypeEditField.Value = 'Constant';
            app.CurrentPressurehPaEditFieldLabel = uilabel(app.LeftPanel);
            app.CurrentPressurehPaEditFieldLabel.Text = 'Current Pressure (hPa)';
            app.CurrentPressurehPaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.CurrentPressurehPaEditField.Editable = 'off';
            app.UpButton = uibutton(app.LeftPanel, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.DownButton = uibutton(app.LeftPanel, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Pressure Controller')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Pressure (hPa)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-15 0];
            app.UIAxes.YLim = [0 1100];
            app.UIAxes.XTick = [-15 -10 -5 0];
            app.UIAxes.XTickLabel = {'-15'; '-10'; '-5'; '0'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SignalGenerator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end