classdef Waveforms < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        SampleRateEditField         matlab.ui.control.NumericEditField
        SampleRateEditFieldLabel    matlab.ui.control.Label
        FrequencyHzEditField        matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel   matlab.ui.control.Label
        AmplitudehPaEditField       matlab.ui.control.NumericEditField
        AmplitudehPaEditFieldLabel  matlab.ui.control.Label
        TargethPaEditField          matlab.ui.control.NumericEditField
        TargethPaEditFieldLabel     matlab.ui.control.Label
        ConfirmButton               matlab.ui.control.Button
        WaveTypeDropDown            matlab.ui.control.DropDown
        WaveTypeDropDownLabel       matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        CallingApp  % Main app object
        WaveStruct = struct('WaveType','Sine Wave',...  % Wave type
            'TargetPressure',500,...                    % in hPa
            'Amplitude',100,...                         % in hPa
            'Frequency',1,...                           % in Hz
            'SampleRate',0.25);                         % in Hz
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, WaveStruct)
            % Store main app object
            app.CallingApp = mainapp;
        
            % Process inputs
            app.WaveTypeDropDown.Value = WaveStruct.WaveType;
            app.TargethPaEditField.Value = WaveStruct.TargetPressure;
            app.AmplitudehPaEditField.Value = WaveStruct.Amplitude;
            app.FrequencyHzEditField.Value = WaveStruct.Frequency;
            app.SampleRateEditField.Value = WaveStruct.SampleRate;
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            % Error messages
            if app.TargethPaEditField.Value+app.AmplitudehPaEditField.Value>1000
                errordlg('Pressure is over 1000 hPa! Pressure will be reset to default values.','Pressure too high');
                app.TargethPaEditField.Value = 900;
                app.AmplitudehPaEditField.Value = 100;
            end
            if app.TargethPaEditField.Value-app.AmplitudehPaEditField.Value<0
                errordlg('Pressure is below 0 hPa! Pressure will be reset to default values.','Pressure too low');
                app.TargethPaEditField.Value = 100;
                app.AmplitudehPaEditField.Value = 100;
            end
            
            % Fill in WaveStruct
            app.WaveStruct.WaveType = app.WaveTypeDropDown.Value;
            app.WaveStruct.TargetPressure = app.TargethPaEditField.Value;
            app.WaveStruct.Amplitude = app.AmplitudehPaEditField.Value;
            app.WaveStruct.Frequency = app.FrequencyHzEditField.Value;
            app.WaveStruct.SampleRate = app.SampleRateEditField.Value;
            
            % Call main app's public function
            updateplot(app.CallingApp,app.WaveStruct);
            
            % Delete the dialog box  
            delete(app)  
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Enable the Plot Options button in main app
            app.CallingApp.WaveGeneratorButton.Enable = 'on';
            
            % Delete the dialog box 
            delete(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.WaveTypeDropDownLabel = uilabel(app.LeftPanel);
            app.WaveTypeDropDownLabel.Text = 'Wave Type';
            app.WaveTypeDropDown = uidropdown(app.LeftPanel);
            app.WaveTypeDropDown.Items = {'Sine Wave', 'Square Wave', 'Triangular Wave', 'Ramp Wave Positive', 'Ramp Wave Negative'};
            app.WaveTypeDropDown.Value = 'Sine Wave';
            app.ConfirmButton = uibutton(app.LeftPanel, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.TargethPaEditFieldLabel = uilabel(app.LeftPanel);
            app.TargethPaEditFieldLabel.Text = 'Target hPa';
            app.TargethPaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TargethPaEditField.Value = 500;
            app.AmplitudehPaEditFieldLabel = uilabel(app.LeftPanel);
            app.AmplitudehPaEditFieldLabel.Text = 'Amplitude hPa';
            app.AmplitudehPaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AmplitudehPaEditField.Value = 100;
            app.FrequencyHzEditFieldLabel = uilabel(app.LeftPanel);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency Hz';
            app.FrequencyHzEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FrequencyHzEditField.Value = 1;
            app.SampleRateEditFieldLabel = uilabel(app.LeftPanel);
            app.SampleRateEditFieldLabel.Text = 'Sample Rate';
            app.SampleRateEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SampleRateEditField.Value = 5;
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Waveforms(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end