classdef WaveformsTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        FrequencyHzEditField        matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel   matlab.ui.control.Label
        CurrenthPaEditField         matlab.ui.control.NumericEditField
        CurrenthPaEditFieldLabel    matlab.ui.control.Label
        AmplitudehPaEditField       matlab.ui.control.NumericEditField
        AmplitudehPaEditFieldLabel  matlab.ui.control.Label
        SetButton                   matlab.ui.control.Button
        TargethPaEditField          matlab.ui.control.NumericEditField
        TargethPaEditFieldLabel     matlab.ui.control.Label
        TypeDropDown                matlab.ui.control.DropDown
        TypeDropDownLabel           matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    methods (Access = private)
        
        function wavePlotter(app)
            % Initialise Values
            waveType = app.TypeDropDown.Value;
            targetPressure = app.TargethPaEditField.Value; % in hPa
            amplitude = app.AmplitudehPaEditField.Value; % in hPa
            frequency = 2*pi*app.FrequencyHzEditField.Value; % in Hz
            sampleRate = 5; % in Hz
            x = 0:1/sampleRate:15;
            
            % Drawing Waves
            if strcmp(waveType, 'Sine Wave')
                plot(app.UIAxes,x,sin(x*frequency)*amplitude+targetPressure)
            elseif strcmp(waveType, 'Square Wave')
                plot(app.UIAxes,x,square(x*frequency)*amplitude+targetPressure)
            elseif strcmp(waveType, 'Triangular Wave')
                plot(app.UIAxes,x,sawtooth(x*frequency,0.5)*amplitude+targetPressure)
            elseif strcmp(waveType, 'Ramp Wave Positive')
                plot(app.UIAxes,x,sawtooth(x*frequency)*amplitude+targetPressure)
            elseif strcmp(waveType, 'Ramp Wave Negative')
                plot(app.UIAxes,x,sawtooth(x*frequency,0)*amplitude+targetPressure)
            else
                plot(app.UIAxes,[0 15],[1000 1000])
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,[7.5 5.5],[0 1000])
                plot(app.UIAxes,[0 15],[0 0])
                hold(app.UIAxes, 'off');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            % Error messages
            if app.TargethPaEditField.Value+app.AmplitudehPaEditField.Value>1000
                errordlg('Pressure is over 1000 hPa! Pressure will be reset to default values.','Pressure too high');
                app.TargethPaEditField.Value = 900;
                app.AmplitudehPaEditField.Value = 100;
            end
            if app.TargethPaEditField.Value+app.AmplitudehPaEditField.Value<0
                errordlg('Pressure is below 0 hPa! Pressure will be reset to default values.','Pressure too low');
                app.TargethPaEditField.Value = 100;
                app.AmplitudehPaEditField.Value = 100;
            end
            
            % Update graph
            wavePlotter(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {182, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TypeDropDownLabel = uilabel(app.LeftPanel);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.LeftPanel);
            app.TypeDropDown.Items = {'Sine Wave', 'Square Wave', 'Triangular Wave', 'Ramp Wave Positive', 'Ramp Wave Negative'};
            app.TypeDropDown.Value = 'Sine Wave';
            app.TargethPaEditFieldLabel = uilabel(app.LeftPanel);
            app.TargethPaEditFieldLabel.Text = 'Target hPa';
            app.TargethPaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TargethPaEditField.Value = 500;
            app.SetButton = uibutton(app.LeftPanel, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.AmplitudehPaEditFieldLabel = uilabel(app.LeftPanel);
            app.AmplitudehPaEditFieldLabel.Text = 'Amplitude hPa';
            app.AmplitudehPaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AmplitudehPaEditField.Value = 100;
            app.CurrenthPaEditFieldLabel = uilabel(app.LeftPanel);
            app.CurrenthPaEditFieldLabel.Text = 'Current hPa';
            app.CurrenthPaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FrequencyHzEditFieldLabel = uilabel(app.LeftPanel);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency Hz';
            app.FrequencyHzEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FrequencyHzEditField.Value = 1;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Wave Generator')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Pressure (hPa)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 15];
            app.UIAxes.YLim = [0 1200];
            app.UIAxes.XTickLabel = {'-15'; '-10'; '-5'; '0'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WaveformsTest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end